classdef StimulusPlayer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectVideoFileButton        matlab.ui.control.Button
        specsTextAreaLabel           matlab.ui.control.Label
        specsTextArea                matlab.ui.control.TextArea
        videofileEditFieldLabel      matlab.ui.control.Label
        videofileEditField           matlab.ui.control.EditField
        SelectDisplayScreenDropDownLabel  matlab.ui.control.Label
        SelectDisplayScreenDropDown  matlab.ui.control.DropDown
        monitorLabel                 matlab.ui.control.Label
        StartF9Button                matlab.ui.control.Button
    end


    properties (Access = private)
         fullFilePath = [fileparts(which('cameraman.tif')) '\rhinos.avi']; % default stimulus path
         displayWindow = 0%index of the video display moniotr
         movieduration
         fps 
         imgw 
         imgh 
         count
    end


    methods (Access = private)

        % Button pushed function: SelectVideoFileButton
        function SelectVideoFileButtonPushed(app, event)
            [fileName,pathName] = uigetfile('pick a video file (*.mp4, *.avi)');
            app.fullFilePath = [pathName fileName]
            app.videofileEditField.Value = fileName;
        end

        % Value changing function: videofileEditField
        function videofileEditFieldValueChanging(app, event)
            app.videofileEditField.Value='';
        end

        % Value changed function: SelectDisplayScreenDropDown
        function SelectDisplayScreenDropDownValueChanged(app, event)
            if strcmp(app.SelectDisplayScreenDropDown.Value,'Control Monitor');
               app.displayWindow = 0;
            else
               app.displayWindow = 1;
            end
        end

        % Button pushed function: StartF9Button
        function StartF9ButtonPushed(app, event)
        try
            VideoPlayer(app.fullFilePath);
        catch E
            errordlg(E.message)
        end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.SelectVideoFileButton = uibutton(app.UIFigure, 'push');
            app.SelectVideoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoFileButtonPushed, true);
            app.SelectVideoFileButton.Text = 'Select Video File';
            app.specsTextAreaLabel = uilabel(app.UIFigure);
            app.specsTextAreaLabel.Text = 'specs';
            app.specsTextArea = uitextarea(app.UIFigure);
            app.videofileEditFieldLabel = uilabel(app.UIFigure);
            app.videofileEditFieldLabel.Text = 'video file';
            app.videofileEditField = uieditfield(app.UIFigure, 'text');
            app.videofileEditField.ValueChangingFcn = createCallbackFcn(app, @videofileEditFieldValueChanging, true);
            app.SelectDisplayScreenDropDownLabel = uilabel(app.UIFigure);
            app.SelectDisplayScreenDropDownLabel.Text = 'Select Display Screen';
            app.SelectDisplayScreenDropDown = uidropdown(app.UIFigure);
            app.SelectDisplayScreenDropDown.Items = {'Control Monitor', 'Experimental Monitor'};
            app.SelectDisplayScreenDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDisplayScreenDropDownValueChanged, true);
            app.SelectDisplayScreenDropDown.Value = 'Control Monitor';
            app.monitorLabel = uilabel(app.UIFigure);
            app.monitorLabel.Text = 'monitor';
            app.StartF9Button = uibutton(app.UIFigure, 'push');
            app.StartF9Button.ButtonPushedFcn = createCallbackFcn(app, @StartF9ButtonPushed, true);
            app.StartF9Button.Text = 'Start (F9)';
        end
    end

    methods (Access = public)

        % Construct app
        function app = StimulusPlayer()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end